classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button2   matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
        UIAxes2   matlab.ui.control.UIAxes
        Button3   matlab.ui.control.Button
        UIAxes3   matlab.ui.control.UIAxes
        UIAxes4   matlab.ui.control.UIAxes
        Button4   matlab.ui.control.Button
        UIAxes5   matlab.ui.control.UIAxes
        Button5   matlab.ui.control.Button
        UIAxes6   matlab.ui.control.UIAxes
        Button6   matlab.ui.control.Button
        UIAxes7   matlab.ui.control.UIAxes
        Button7   matlab.ui.control.Button
        UIAxes8   matlab.ui.control.UIAxes
        Button8   matlab.ui.control.Button
        UIAxes9   matlab.ui.control.UIAxes
        Button9   matlab.ui.control.Button
        Button1   matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button2
        function ButtonPushed2(app, event)
            global A
            global B
            A = imread('money.jpg');
            B=rgb2gray(A);
            imshow(B, 'Parent', app.UIAxes2);            
        end

        % Button pushed function: Button4
        function B4(app, event)
            global B
            H = imhist(B);
            bar(app.UIAxes4,H)                    
        end

        % Button pushed function: Button3
        function BUttonPushed3(app, event)
            global B
            global C
            C= imbinarize(B);
            imshow(C, 'Parent', app.UIAxes3);
        end

        % Button pushed function: Button5
        function B5(app, event)
            global A
            global K
            K = imadjust(A,[.2 .3 0;.6 .7 1],[]);
            imshow(K, 'Parent', app.UIAxes5);
        end

        % Button pushed function: Button6
        function B6(app, event)
            global B
            global M
            M = imadjust(B,[.2 ,.7],[]);
            imshow(M, 'Parent', app.UIAxes6);
        end

        % Button pushed function: Button7
        function B7(app, event)
            global M
            Z = imhist(M);
            bar(app.UIAxes7,Z)
        end

        % Button pushed function: Button8
        function B8(app, event)
            global B
            global S
            S = histeq(B);
            imshow(S, 'Parent', app.UIAxes8);
        end

        % Button pushed function: Button9
        function B9(app, event)
            global B
            global P
            P = imresize(B,[150 100]);
            imshow(P, 'Parent', app.UIAxes9);
        end

        % Button pushed function: Button1
        function B1(app, event)
            global A
            A = imread('money.jpg');
            imshow(A, 'Parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button2.Text = 'Button2';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Monochrome')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @BUttonPushed3, true);
            app.Button3.Text = 'Button3';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Black and White')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Histogram of Monochrome')
            xlabel(app.UIAxes4, 'Intensity')
            ylabel(app.UIAxes4, 'No. of Pixels')
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @B4, true);
            app.Button4.Text = 'Button4';
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Adjusted Original')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @B5, true);
            app.Button5.Text = 'Button5';
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Adjusted Monochrome')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, '')
            app.Button6 = uibutton(app.UIFigure, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @B6, true);
            app.Button6.Text = 'Button6';
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Histogram of Adjusted Monochrome')
            xlabel(app.UIAxes7, 'Intensity')
            ylabel(app.UIAxes7, 'No. of Pixels')
            app.Button7 = uibutton(app.UIFigure, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @B7, true);
            app.Button7.Text = 'Button7';
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, 'Histeq of Monochrome')
            xlabel(app.UIAxes8, '')
            ylabel(app.UIAxes8, '')
            app.Button8 = uibutton(app.UIFigure, 'push');
            app.Button8.ButtonPushedFcn = createCallbackFcn(app, @B8, true);
            app.Button8.Text = 'Button8';
            app.UIAxes9 = uiaxes(app.UIFigure);
            title(app.UIAxes9, 'Resized Monochrome')
            xlabel(app.UIAxes9, '')
            ylabel(app.UIAxes9, '')
            app.Button9 = uibutton(app.UIFigure, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @B9, true);
            app.Button9.Text = 'Button9';
            app.Button1 = uibutton(app.UIFigure, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @B1, true);
            app.Button1.Text = 'Button1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end